classdef xinhao < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Panel_4          matlab.ui.container.Panel
        TextArea_2       matlab.ui.control.TextArea
        TextArea_2Label  matlab.ui.control.Label
        Button_11        matlab.ui.control.Button
        Panel_3          matlab.ui.container.Panel
        DropDown_4       matlab.ui.control.DropDown
        Label_6          matlab.ui.control.Label
        DropDown_3       matlab.ui.control.DropDown
        Label_5          matlab.ui.control.Label
        DropDown_2       matlab.ui.control.DropDown
        Label_4          matlab.ui.control.Label
        Button_10        matlab.ui.control.Button
        UIAxes3          matlab.ui.control.UIAxes
        TextArea         matlab.ui.control.TextArea
        Panel_2          matlab.ui.container.Panel
        Button_9         matlab.ui.control.Button
        Button_7         matlab.ui.control.Button
        Button_6         matlab.ui.control.Button
        Button_5         matlab.ui.control.Button
        UIAxes2          matlab.ui.control.UIAxes
        Panel            matlab.ui.container.Panel
        Button_8         matlab.ui.control.Button
        EditField_2      matlab.ui.control.NumericEditField
        Label_3          matlab.ui.control.Label
        EditField        matlab.ui.control.NumericEditField
        Label_2          matlab.ui.control.Label
        Button_4         matlab.ui.control.Button
        DropDown         matlab.ui.control.DropDown
        Label            matlab.ui.control.Label
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        recorder % Description
        %aduioData % Description
        signalData % 存储所有可用的信号
        selectedSignal1 % 第一个选中的信号
        selectedSignal2 % 第二个选中的信号 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 生成一些默认信号 (假设采样率为 fs)
            fs = 44100; 
            t = 0:1/fs:1; % 1s 时间向量
        
            app.signalData.Sine = sin(2*pi*10*t); % 10Hz 正弦波
            app.signalData.Square = square(2*pi*10*t); % 10Hz 方波
            app.signalData.Chirp = chirp(t, 0, 1, 100); % 线性 Chirp 信号
            app.signalData.Step = double(t > 0.5); % 阶跃信号
            app.signalData.Recorded = []; % 录音数据，后面填充
            app.selectedSignal1 = app.signalData.Sine;
            app.selectedSignal2 = app.signalData.Sine;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            fs = 1000 ;
            t = 0:1/fs:2;
            freq = app.EditField.Value;
            amp = app.EditField_2.Value;
            % choose
            switch app.DropDown.Value
                case '正弦信号'
                    y = amp * sin(2 * pi * freq * t);
                    % 绘制信号
                    plot(app.UIAxes, t, y);
                    title(app.UIAxes, '正弦信号');
                    xlabel(app.UIAxes, '时间 (s)');
                    ylabel(app.UIAxes, '幅度');
                case '方波信号'
                    y = amp * square(2 * pi * freq * t);
                    plot(app.UIAxes, t, y);
                    title(app.UIAxes, '方波信号');
                    xlabel(app.UIAxes, '时间 (s)');
                    ylabel(app.UIAxes, '幅度');
                case '阶跃信号'
                    y = amp * (t >= 0.5);
                    plot(app.UIAxes, t, y);
                    title(app.UIAxes, '阶跃信号');
                    xlabel(app.UIAxes, '时间 (s)');
                    ylabel(app.UIAxes, '幅度');
                case 'chirp信号'
                    y = amp * chirp(t, 0, 1, freq);
                    plot(app.UIAxes, t, y);
                    title(app.UIAxes, 'chirp信号');
                    xlabel(app.UIAxes, '时间 (s)');
                    ylabel(app.UIAxes, '幅度');
            end
            
        end

        % Callback function
        function Button_8Pushed(app, event)
            %clear
            cla(app.UIAxes);
            title(app.UIAxes,'信号已清除');
            xlabel(app.UIAxes, '时间 (s)');
            ylabel(app.UIAxes, '幅度');
        end

        % Button pushed function: Button_8
        function clear(app, event)
            %clear
            cla(app.UIAxes);
            title(app.UIAxes,'信号已清除');
            xlabel(app.UIAxes, '时间 (s)');
            ylabel(app.UIAxes, '幅度');   
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            % fs = 44100; % 采样率
            % app.recorder = audiorecorder(fs, 16, 2); % 立体声 16 位
            % record(app.recorder); % 开始录音
            % 
            % % 实时更新波形
            % while isrecording(app.recorder)
            %     audioData = getaudiodata(app.recorder);
            %     plot(app.UIAxes2, audioData);
            %     title(app.UIAxes2, '实时录音信号');
            %     xlabel(app.UIAxes2, '采样点');
            %     ylabel(app.UIAxes2, '幅度');
            %     pause(0.1); % UI 刷新
            % end
            fs = 44100; % 采样率
            app.recorder = audiorecorder(fs, 16, 2); % 立体声 16 位
            record(app.recorder); % 开始录音
            disp('录音开始...');
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            % stop(app.recorder); % 停止录音
            % audioData = getaudiodata(app.recorder);
            % 
            % % 录音结束后，波形固定
            % plot(app.UIAxes2, audioData);
            % title(app.UIAxes2, '最终录音信号');
            if ~isempty(app.recorder)
                stop(app.recorder); % 停止录音
                disp('录音结束。');
        
                % 获取录音数据
                audioData = getaudiodata(app.recorder);
                app.signalData.Recorded = audioData;
                %app.audioData = getaudiodata(app.recorder); % 存储录音数据
                % 在 UIAxes2 上绘制录音波形
                plot(app.UIAxes2, audioData);
                title(app.UIAxes2, '录音信号');
                xlabel(app.UIAxes2, '时间');
                ylabel(app.UIAxes2, '幅度');
            else
                disp('请先开始录音！');
            end
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
               if ~isempty(app.recorder)
                    audioData = getaudiodata(app.recorder);
                    gain = 5;
                    audioData = audioData   *  gain;
                    sound(audioData, app.recorder.SampleRate);
                    disp('正在播放录音...');
               else
                    disp('没有可播放的录音，请先录音！');
               end
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            %app.audioData = []; % 清空存储的录音    
            cla(app.UIAxes2);
            title(app.UIAxes2,'信号已清除');
            xlabel(app.UIAxes2, '时间 (s)');
            ylabel(app.UIAxes2, '幅度');   
        end

        % Callback function
        function DropDown_3Clicked(app, event)
            item = event.InteractionInformation.Item;
            
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)

            % 获取用户选择的信号类型
            selectedSignal = app.DropDown_3.Value;
            
            % 显示选择的信号
            disp(['信号1 选择了: ', selectedSignal]);
            
            % 赋值到 app 变量，便于后续计算
            switch selectedSignal
                case '正弦信号'
                    app.selectedSignal1 = app.signalData.Sine;
                case '方波信号'
                    app.selectedSignal1 = app.signalData.Square;
                case 'Chirp信号'
                    app.selectedSignal1 = app.signalData.Chirp;
                case '阶跃信号'
                    app.selectedSignal1 = app.signalData.Step;
                case '采集的麦克风信号'
                    app.selectedSignal1 = app.signalData.Recorded;
                otherwise
                    app.selectedSignal1 = [];
            end
        end

        % Value changed function: DropDown_4
        function DropDown_4ValueChanged(app, event)
             % 获取用户选择的信号类型
            selectedSignal = app.DropDown_4.Value;
            
            % 显示选择的信号
            disp(['信号2 选择了: ', selectedSignal]);
            
            % 赋值到 app 变量，便于后续计算
            switch selectedSignal
                case '正弦信号'
                    app.selectedSignal2 = app.signalData.Sine;
                case '方波信号'
                    app.selectedSignal2 = app.signalData.Square;
                case 'Chirp信号'
                    app.selectedSignal2 = app.signalData.Chirp;
                case '阶跃信号'
                    app.selectedSignal2 = app.signalData.Step;
                case '采集的麦克风信号'
                    app.selectedSignal2 = app.signalData.Recorded;
                otherwise
                    app.selectedSignal2 = [];
            end
            
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            if isempty(app.selectedSignal1) || isempty(app.selectedSignal2)
                uialert(app.UIFigure, '请先选择两个信号', '错误');
                return;
            end
        
            operation = app.DropDown_2.Value;
            
            % 对于加法和减法，检查信号长度是否一致
            if strcmp(operation, '加法') || strcmp(operation, '减法')
                len1 = length(app.selectedSignal1);
                len2 = length(app.selectedSignal2);
                if len1 ~= len2
                    uialert(app.UIFigure, '两个信号的长度不匹配，无法执行加法或减法运算', '错误');
                    return;
                end
            end
            
            switch operation
                case '加法'
                    result = app.selectedSignal1 + app.selectedSignal2;
                case '减法'
                    result = app.selectedSignal1 - app.selectedSignal2;
                case '卷积'
                      % 如果信号不是向量，则取第一个通道
                    s1 = app.selectedSignal1;
                    s2 = app.selectedSignal2;
                    if ~isvector(s1)
                        s1 = s1(:,1);  % 选择第一个通道
                    end
                    if ~isvector(s2)
                        s2 = s2(:,1);  % 选择第一个通道
                    end
                    result = conv(s1, s2);
                case '相关性'
                    % 如果信号不是向量，则取第一个通道
                    s1 = app.selectedSignal1;
                    s2 = app.selectedSignal2;
                    if ~isvector(s1)
                        s1 = s1(:,1);  % 选择第一个通道
                    end
                    if ~isvector(s2)
                        s2 = s2(:,1);  % 选择第一个通道
                    end
                    result = xcorr(s1, s2);
                otherwise
                    uialert(app.UIFigure, '未知运算类型', '错误');
                    return;
            end
            
            plot(app.UIAxes3, result);
            title(app.UIAxes3, ['运算结果: ' operation]);
            xlabel(app.UIAxes3, '时间');
            ylabel(app.UIAxes3, '幅度');
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
           app.TextArea_2.Value = {''}; % 先清空文本框
            % 检查录音数据是否存在
            if isempty(app.signalData.Recorded)
                uialert(app.UIFigure, '录音数据为空，请先进行录音！', '错误');
                return;
            end
        
            % 获取录音数据，若为立体声则转换为单通道（取均值）
            audioData = app.signalData.Recorded;
            if size(audioData,2) > 1
                audioData = mean(audioData,2);
            end
        
            % 定义采样率，需与录音时一致（例如44100 Hz）
            fs = 44100;
            
            % 生成时间向量
            t = (0:length(audioData)-1)/fs;
            
            % 时域分析
            peakAmplitude = max(abs(audioData));     % 峰值幅度
            rmsValue = rms(audioData);                 % 均方根值
            zeroCrossings = sum(abs(diff(audioData > 0)));  % 过零率
            
            % 频域分析（计算 FFT）
            N = length(audioData);
            Y = fft(audioData);
            f = (0:N-1)*(fs/N);
            % 保留正频率部分
            if mod(N,2)==0
                Y = Y(1:N/2+1);
                f = f(1:N/2+1);
            else
                Y = Y(1:ceil(N/2));
                f = f(1:ceil(N/2));
            end
            mag = abs(Y);
            
            % 计算频域额外参数
            % 谱重心（频谱质心）
            spectralCentroid = sum(f .* mag) / sum(mag);
            % 谱带宽（频谱标准差）
            spectralBandwidth = sqrt(sum(((f - spectralCentroid).^2).*mag) / sum(mag));
            % 频谱峰值：幅值最大的频率
            [~, idxPeak] = max(mag);
            peakFrequency = f(idxPeak);
            
            % 将所有分析结果显示在 TextArea_2 上
            analysisStr = sprintf(['时域分析结果:\n' ...
                                   '峰值幅度: %.4f\nRMS值: %.4f\n过零率: %d\n\n' ...
                                   '频域分析结果:\n' ...
                                   '谱峰频率: %.2f Hz\n谱重心: %.2f Hz\n谱带宽: %.2f Hz'], ...
                                   peakAmplitude, rmsValue, zeroCrossings, peakFrequency, spectralCentroid, spectralBandwidth);
            app.TextArea_2.Value = {analysisStr};
            
            % 绘制时域图（显示在 UIAxes3 上）
            plot(app.UIAxes3, t, audioData);
            xlabel(app.UIAxes3, '时间 (秒)');
            ylabel(app.UIAxes3, '幅度');
            title(app.UIAxes3, '录音信号时域分析');
            
                % 绘制频域图在 UIAxes_Freq 中
                plot(app.UIAxes, f, abs(Y));
                xlabel(app.UIAxes, '频率 (Hz)');
                ylabel(app.UIAxes, '幅度');
                title(app.UIAxes, '录音信号频域分析');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '典型信号演示';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label = uilabel(app.Panel);
            app.Label.Text = '选择生成的信号类型';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'正弦信号', '方波信号', 'chirp信号', '阶跃信号'};
            app.DropDown.Value = '正弦信号';
            app.Button_4 = uibutton(app.Panel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '生成信号';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '频率';
            app.EditField = uieditfield(app.Panel, 'numeric');
            app.EditField.Value = 10;
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '幅度';
            app.EditField_2 = uieditfield(app.Panel, 'numeric');
            app.EditField_2.Value = 2;
            app.Button_8 = uibutton(app.Panel, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @clear, true);
            app.Button_8.Text = '清除信号';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '语言信号采集';
            app.UIAxes2 = uiaxes(app.Panel_2);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Button_5 = uibutton(app.Panel_2, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '开始采集';
            app.Button_6 = uibutton(app.Panel_2, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '结束采集';
            app.Button_7 = uibutton(app.Panel_2, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '播放音频';
            app.Button_9 = uibutton(app.Panel_2, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '清除录音';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Proury''s MATLAB信号分析仪'};
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = '信号运算';
            app.UIAxes3 = uiaxes(app.Panel_3);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.Button_10 = uibutton(app.Panel_3, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '执行运算';
            app.Label_4 = uilabel(app.Panel_3);
            app.Label_4.Text = '选择运算类型';
            app.DropDown_2 = uidropdown(app.Panel_3);
            app.DropDown_2.Items = {'加法', '减法', '卷积', '相关性', '', ''};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = '加法';
            app.Label_5 = uilabel(app.Panel_3);
            app.Label_5.Text = '信号1';
            app.DropDown_3 = uidropdown(app.Panel_3);
            app.DropDown_3.Items = {'正弦信号', '方波信号', 'Chirp信号', '阶跃信号', '采集的麦克风信号'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Value = '正弦信号';
            app.Label_6 = uilabel(app.Panel_3);
            app.Label_6.Text = '信号2';
            app.DropDown_4 = uidropdown(app.Panel_3);
            app.DropDown_4.Items = {'正弦信号', '方波信号', 'chirp信号', '阶跃信号', '采集的麦克风信号'};
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_4ValueChanged, true);
            app.DropDown_4.Value = '正弦信号';
            app.Panel_4 = uipanel(app.UIFigure);
            app.Panel_4.Title = 'Panel';
            app.Button_11 = uibutton(app.Panel_4, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '计算分析参数';
            app.TextArea_2Label = uilabel(app.Panel_4);
            app.TextArea_2Label.Text = 'Text Area';
            app.TextArea_2 = uitextarea(app.Panel_4);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = xinhao

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end